classdef DoAn4_20200331 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        OptionListBoxLabel      matlab.ui.control.Label
        OptionListBox           matlab.ui.control.ListBox
        UIAxes                  matlab.ui.control.UIAxes
        dydxfxyEditFieldLabel   matlab.ui.control.Label
        PTviphan                matlab.ui.control.EditField
        iukinuy0EditFieldLabel  matlab.ui.control.Label
        giatridauY0             matlab.ui.control.EditField
        GitrucuimngxLabel       matlab.ui.control.Label
        giatriMangx             matlab.ui.control.EditField
        SonconNEditFieldLabel   matlab.ui.control.Label
        sodoanconN              matlab.ui.control.EditField
        SaisnucEditFieldLabel   matlab.ui.control.Label
        Saiso                   matlab.ui.control.EditField
        SloveandDraw            matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SloveandDraw
        function SloveandDrawAction(app, event)
          try
            fxy = app.PTviphan.Value ;
            y0 = app.giatridauY0.Value ;
            x = app.giatriMangx.Value ;
            N = app.sodoanconN.Value ;
          if isempty(fxy) == 1
            msgbox('Nhap ham so can tinh','Error','error');
          elseif isempty(y0) == 1
            msgbox('Vui long nhap dieu kien dau','Error','error');
          elseif isempty(x) == 1
            msgbox('Vui long nhap cac  gia tri dau cuoi mang x','Error','error');
          elseif isempty(N) == 1
            msgbox('Vui long nhap so doan con','Error','error');
          else
             fxy = str2func(['@(x,y)',fxy]) ;
             y0 = str2num(y0) ;
             x = str2num(x) ;
             N = str2num(N) ;
             h = (x(length(x))  - x(1))/N 
            switch app.OptionListBox.Value
                case 'Euler'
                     y(1) = y0; 
                      x = x(1):h:x(2) ;
                for i = 1:(N)
                       y(i+1) = y(i) + h*fxy(x(i), y(i));   
                end
                     plot(app.UIAxes,x,y,'k') ;
                case 'Trung điểm'   
                    y(1) = y0;  
                    x = x(1):h:x(length(x)) ;
                  for i = 1:N
                     y(i+1) = y(i) + (h/2)*fxy(x(i), y(i));  
                     y(i+1) = y(i) + h*fxy(x(i)+h/2, y(i+1));
                  end
                    plot(app.UIAxes,x,y,'r') ;  
                case 'Hình thang'
                    saiso = app.Saiso.Value ;
                  if isempty(saiso) == 1 
            msgbox('Vui long nhap gia tri sai so','Error','error');
                  else
                    e = str2num(saiso) ;
                     yo(1,1) = y0;  y(1,1) = y0; 
                     x = x(1):h:x(length(x)) ;
                 for m=2:10^9
                    for i=1:N
                        yo(1,i+1) = yo(1,i) + h*fxy(x(i), yo(1,i)) ;
                        yo(m,i+1)= yo(1,i) + h/2 *(fxy(x(i), yo(1,i)) + fxy(x(i+1),yo(m-1,i+1)));
                       if abs(yo(m,i+1) - yo(m-1,i+1)) <= e
                            break 
                       end  
                    end
                end
             k = size(yo) ; 
            for h = k(2):-1:1
                for q = k(1):-1:1
                    if yo(q,h) ~= 0
                        y(1,h) = yo(q,h);
                         break
                    end
                end
            end
                    plot(app.UIAxes,x,y,'m') ;
                  end
                case 'R-K'
                     y(1) = y0; 
                     x = x(1):h:x(length(x))   ;
                    for i = 1:N
                        k1 = h*fxy( x(i), y(i) );
                        k2 = h*fxy( x(i)+0.5*h, y(i)+0.5*k1 );
                        k3 = h*fxy( x(i)+0.5*h, y(i)+0.5*k2 );
                        k4 = h*fxy( x(i)+h, y(i)+k3 );
                        y(i+1) = y(i) + (1/6)*(k1 + 2*k2 + 2*k3 + k4) ;
                    end
                    plot(app.UIAxes,x,y,'b') ;
            end
          end
          catch error
              switch (error.identifier)
                case'MATLAB:UndefinedFunction'
                    msgbox('Nhap dung dinh dang ham va bien','Error','error');
                otherwise 
                    msgbox({error.identifier,error.message},'Error','error'); 
              end  
          end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionListBoxLabel = uilabel(app.UIFigure);
            app.OptionListBoxLabel.Text = 'Option';
            app.OptionListBox = uilistbox(app.UIFigure);
            app.OptionListBox.Items = {'Euler', 'Trung điểm', 'Hình thang', 'R-K'};
            app.OptionListBox.Value = 'Euler';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị y = f(x)')
            xlabel(app.UIAxes, 'Trục x')
            ylabel(app.UIAxes, 'Trục  y')
            app.dydxfxyEditFieldLabel = uilabel(app.UIFigure);
            app.dydxfxyEditFieldLabel.Text = 'dy/dx = f(x,y)';
            app.PTviphan = uieditfield(app.UIFigure, 'text');
            app.iukinuy0EditFieldLabel = uilabel(app.UIFigure);
            app.iukinuy0EditFieldLabel.Text = 'Điều kiện đầu y0';
            app.giatridauY0 = uieditfield(app.UIFigure, 'text');
            app.GitrucuimngxLabel = uilabel(app.UIFigure);
            app.GitrucuimngxLabel.Text = 'Giá trị đầu cuối mảng x';
            app.giatriMangx = uieditfield(app.UIFigure, 'text');
            app.SonconNEditFieldLabel = uilabel(app.UIFigure);
            app.SonconNEditFieldLabel.Text = 'Số đoạn con N';
            app.sodoanconN = uieditfield(app.UIFigure, 'text');
            app.SaisnucEditFieldLabel = uilabel(app.UIFigure);
            app.SaisnucEditFieldLabel.Text = 'Sai số (nếu có)';
            app.Saiso = uieditfield(app.UIFigure, 'text');
            app.SloveandDraw = uibutton(app.UIFigure, 'state');
            app.SloveandDraw.ValueChangedFcn = createCallbackFcn(app, @SloveandDrawAction, true);
            app.SloveandDraw.Text = {'GIẢI VÀ VẼ'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAn4_20200331

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end