classdef Doan_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PPGiai                         matlab.ui.container.ButtonGroup
        Chiadoi                        matlab.ui.control.RadioButton
        Lap                            matlab.ui.control.RadioButton
        TiepTuyen                      matlab.ui.control.RadioButton
        plotAxe                        matlab.ui.control.UIAxes
        HmfxEditFieldLabel             matlab.ui.control.Label
        funtionEdit                    matlab.ui.control.EditField
        KhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        phanlyEdit                     matlab.ui.control.EditField
        SaisEditFieldLabel             matlab.ui.control.Label
        Saiso                          matlab.ui.control.EditField
        SloveDraw                      matlab.ui.control.Button
        SlnlpEditFieldLabel            matlab.ui.control.Label
        soLanLap                       matlab.ui.control.NumericEditField
        HmlpEditFieldLabel             matlab.ui.control.Label
        HamLap                         matlab.ui.control.EditField
        NghimEditFieldLabel            matlab.ui.control.Label
        giatriNghiem                   matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SloveDraw
        function SloveDrawButtonPushed(app, event)
       try
           x = app.phanlyEdit.Value ;
           f = app.funtionEdit.Value ;
           saiso = app.Saiso.Value ;
           if isempty(f) == 1
              msgbox('Vui long nhap ham f(x)','Error','error'); 
          elseif isempty(x) ==1
              msgbox('Vui long nhap khoang phan ly nghiem','Error','error');  
          elseif isempty(saiso) ==1
              msgbox('Vui long nhap gia tri sai so','Error','error');
           elseif isempty(app.HamLap.Value) ==1 && app.Lap.Value ==1
              msgbox('Voi phuong phap lap, vui long nhap ham lap','Error','error'); 
          end
           x = str2num(x) ;
           x = linspace(x(1),x(2), 500) ;
           f = str2func(['@(x)', f] ) ;
           saiso = str2num(saiso) ;
           a = x(1); b = x(length(x));
           if f(a)*f(b) > 0 
              msgbox('Vui long thay doi khoang phan ly nghiem','Error','error'); 
           else
            switch 1
               case app.Chiadoi.Value == 1
                    e = 1; 
                    solanlap = 0;
                while  (e >= saiso)  
                    c = (a + b)/2 ;
                 if f(c) * f(a) < 0
                    b = c;
                 else
                    a = c;
                 end
                      e = b - a; 
                      solanlap = solanlap + 1;
                  if e < saiso 
                    nghiem = a ;
                  end 
                end
                app.giatriNghiem.Value = nghiem ; 
                app.soLanLap.Value = solanlap ;
                plot(app.plotAxe,x,f(x),'r'); 
                case app.Lap.Value == 1
                   g = app.HamLap.Value ;
                   g = str2func(['@(x)', g] ) ;
                   c = a ; k = 0 ;y=0;
                 while 1 
                     y = g(c) ;
                     k = k + 1;
                  if abs(y - c) < saiso
                         break
                  else
                       c = y ;
                  end 
                     nghiem = c ;
                     solanlap = k ;    
                 end
               app.giatriNghiem.Value = nghiem  ; 
               app.soLanLap.Value =  solanlap ; 
               plot(app.plotAxe,x,f(x),'g');
                case app.TiepTuyen.Value==1
                syms k;  
                fd1 = str2func(['@(k)' char(diff(f(k)))]);
                c = 0 ; lap = 0; y =a;
                 while 1  
                     c = y ;
                     a=round(f(c),5)
                     k=round(fd1(c),5)
                     z = f(c)/fd1(c) 
                     lap = lap + 1;
                     y = round(c - z ,5)
                     if abs(y - c) < saiso
                         break
                     end 
                     nghiem = y ;
                     solanlap = lap ;
                 end
                app.giatriNghiem.Value =nghiem  ; 
                app.soLanLap.Value =  solanlap ;      
                plot(app.plotAxe,x,f(x),'b');
            end
           end
        catch error
         switch error.identifier
               case 'MATLAB:mpower:notScalarAndSquareMatrix'
              msgbox({'Loi nhan ma tran' 'Den nhan tung phan tu, dung dau "." truoc lenh'},'Error','error');  
         end 
       end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PPGiai = uibuttongroup(app.UIFigure);
            app.Chiadoi = uiradiobutton(app.PPGiai);
            app.Chiadoi.Text = 'Chia đôi';
            app.Chiadoi.Value = true;
            app.Lap = uiradiobutton(app.PPGiai);
            app.Lap.Text = 'Lặp';
            app.TiepTuyen = uiradiobutton(app.PPGiai);
            app.TiepTuyen.Text = 'Tiếp tuyến';
            app.plotAxe = uiaxes(app.UIFigure);
            title(app.plotAxe, 'Đồ thị f(x)')
            xlabel(app.plotAxe, 'Trục x')
            ylabel(app.plotAxe, 'Trục y')
            app.HmfxEditFieldLabel = uilabel(app.UIFigure);
            app.HmfxEditFieldLabel.Text = 'Hàm f(x)';
            app.funtionEdit = uieditfield(app.UIFigure, 'text');
            app.funtionEdit.Value = 'x+0.13*log(x)-2';
            app.KhongphnlynghimEditFieldLabel = uilabel(app.UIFigure);
            app.KhongphnlynghimEditFieldLabel.Text = 'Khoảng phân ly nghiệm';
            app.phanlyEdit = uieditfield(app.UIFigure, 'text');
            app.phanlyEdit.Value = '1,2';
            app.SaisEditFieldLabel = uilabel(app.UIFigure);
            app.SaisEditFieldLabel.Text = 'Sai số';
            app.Saiso = uieditfield(app.UIFigure, 'text');
            app.Saiso.Value = '0.01';
            app.SloveDraw = uibutton(app.UIFigure, 'push');
            app.SloveDraw.ButtonPushedFcn = createCallbackFcn(app, @SloveDrawButtonPushed, true);
            app.SloveDraw.Text = ' GIẢI VÀ VẼ';
            app.SlnlpEditFieldLabel = uilabel(app.UIFigure);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.soLanLap = uieditfield(app.UIFigure, 'numeric');
            app.HmlpEditFieldLabel = uilabel(app.UIFigure);
            app.HmlpEditFieldLabel.Text = 'Hàm lặp';
            app.HamLap = uieditfield(app.UIFigure, 'text');
            app.HamLap.Value = '2-0.13*log(x)';
            app.NghimEditFieldLabel = uilabel(app.UIFigure);
            app.NghimEditFieldLabel.Text = 'Nghiệm ';
            app.giatriNghiem = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Doan_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end