classdef week5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        myAxe                   matlab.ui.control.UIAxes
        Data1EditFieldLabel     matlab.ui.control.Label
        dataEdit_1              matlab.ui.control.EditField
        Data2EditFieldLabel     matlab.ui.control.Label
        dataEdit_2              matlab.ui.control.EditField
        FunctionEditFieldLabel  matlab.ui.control.Label
        functionEdit            matlab.ui.control.EditField
        drawButton              matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function dataEdit_2ValueChanged(app, event)
            value = app.dataEdit_2.Value;
            
        end

        % Value changed function: functionEdit
        function functionEditValueChanged(app, event)
            value = app.functionEdit.Value;
            
        end

        % Value changed function: drawButton
        function drawButtonPushed(app, event)
           try
             var1 = app.dataEdit_1.Value;
             var2 = app.dataEdit_2.Value;
             fxy = app.functionEdit.Value;
             
          if  isempty(var1) == 0 && isempty(var2) == 1
              var1 = str2num(var1);
              fxy = str2func(['@(x)' fxy]);
              g = fxy(var1);
              plot(app.myAxe,var1,g);
          elseif isempty(var1) == 1 && isempty(var2) == 0
              var2 = str2num(var2);
              fxy = str2func(['@(x)' fxy]);
              g = fxy(var2);
              plot(app.myAxe,var2,g);
          elseif isempty(var1) == 0 && isempty(var2) == 0
              if contains(fxy,"x") == 0 || contains(fxy,"y") ==0
                  msgbox('So luong du lieu nhieu hon so luong bien cua ham','Error','error');
              else
                  var1 = str2num(var1);
                  var2 = str2num(var2);
                  fxy = str2func(['@(x,y)' fxy]);
               for i = 1:length(var1)
                   for j = 1:length(var2)
                      g(i,j) = fxy(var1(i),var2(j));
                   end
               end
               mesh(app.myAxe,var1,var2,g);
              end 
          else    
             msgbox('Vui long nhap it nhat 1 o du lieu','Error','error');
          end    
         catch error
           switch (error.identifier)
           case 'MATLAB:UndefinedFunction'
             msgbox('Chi nhap ham mot bien','Error','error');
           case 'MATLAB:m_missing_operator'
             msgbox('Sai dinh dang ham, vui long nhap lai','Error','error');
           case 'MATLAB:mpower:notScalarAndSquareMatrix'
             msgbox({'Loi nhan ma tran' 'Den nhan tung phan tu, dung dau "." truoc lenh'},'Error','error');
           otherwise
          msgbox({error.identifier,error.message},'Error','error');
           end
           end         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.myAxe = uiaxes(app.UIFigure);
            title(app.myAxe, 'Title')
            xlabel(app.myAxe, 'X')
            ylabel(app.myAxe, 'Y')
            app.Data1EditFieldLabel = uilabel(app.UIFigure);
            app.Data1EditFieldLabel.Text = 'Data1';
            app.dataEdit_1 = uieditfield(app.UIFigure, 'text');
            app.dataEdit_1.Value = '-20:0.1:20';
            app.Data2EditFieldLabel = uilabel(app.UIFigure);
            app.Data2EditFieldLabel.Text = 'Data 2';
            app.dataEdit_2 = uieditfield(app.UIFigure, 'text');
            app.dataEdit_2.Value = '-20:0.1:20';
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.functionEdit = uieditfield(app.UIFigure, 'text');
            app.functionEdit.ValueChangedFcn = createCallbackFcn(app, @functionEditValueChanged, true);
            app.functionEdit.Value = 'x.^2 + y.^3 - 4';
            app.drawButton = uibutton(app.UIFigure, 'state');
            app.drawButton.ValueChangedFcn = createCallbackFcn(app, @drawButtonPushed, true);
            app.drawButton.Text = 'DRAW';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = week5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end